classdef assignment4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RGBComponentEditField       matlab.ui.control.NumericEditField
        RGBComponentEditFieldLabel  matlab.ui.control.Label
        TransformButton             matlab.ui.control.Button
        OPENButton                  matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OPENButton
        function OPENButtonPushed(app, event)
            [fname,pname] = uigetfile('*.*','Enter data file'); % image selection
            global A;
            A=imread(fullfile(pname, fname)); % Reading of source image
            imshow(A,'Parent',app.UIAxes); title(app.UIAxes, "Source Image");
        end

        % Button pushed function: TransformButton
        function TransformButtonPushed(app, event)
            global A;
            global B;
            %G =str2double(get(app.RGBComponentEditField, 'String'));
            G = app.RGBComponentEditField.Value; % assigning the RGB value to a variable
            B=A(:,:,G); % selection of the RGB
            [N1,N2]=size(B);
            imshow(B,'Parent',app.UIAxes2); title(app.UIAxes2, "Transformed Result");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Source Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Result')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.OPENButton = uibutton(app.UIFigure, 'push');
            app.OPENButton.ButtonPushedFcn = createCallbackFcn(app, @OPENButtonPushed, true);
            app.OPENButton.Text = 'OPEN';
            app.TransformButton = uibutton(app.UIFigure, 'push');
            app.TransformButton.ButtonPushedFcn = createCallbackFcn(app, @TransformButtonPushed, true);
            app.TransformButton.Text = 'Transform';
            app.RGBComponentEditFieldLabel = uilabel(app.UIFigure);
            app.RGBComponentEditFieldLabel.Text = 'RGB Component';
            app.RGBComponentEditField = uieditfield(app.UIFigure, 'numeric');
            app.RGBComponentEditField.Limits = [1 3];
            app.RGBComponentEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignment4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end