classdef assignment5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        InfoEditField             matlab.ui.control.EditField
        InfoEditFieldLabel        matlab.ui.control.Label
        Text_dispButton           matlab.ui.control.Button
        Text_inputEditField       matlab.ui.control.EditField
        Text_inputEditFieldLabel  matlab.ui.control.Label
        AngleEditField            matlab.ui.control.NumericEditField
        AngleEditFieldLabel       matlab.ui.control.Label
        RotateButton              matlab.ui.control.Button
        OpenFileButton            matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)

        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            [fname,pname] = uigetfile('*.*','Enter data file');
            global A;
            A=imread(fullfile(pname, fname));
            imshow(A,'Parent',app.UIAxes); title(app.UIAxes, "Source Image");
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            global A;
            angle = app.AngleEditField.Value; %assigning the angle value to a variable
            I = imrotate(A,angle); % image rotate function
            imshow(I,'Parent',app.UIAxes2); title(app.UIAxes2, "Rotation Result");
        end

        % Button pushed function: Text_dispButton
        function Text_dispButtonPushed(app, event)
            global text;
            global info;

            text = app.Text_inputEditField.Value;
            info = app.InfoEditField.Value;

            info = text;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes2.Visible = 'off';
            app.OpenFileButton = uibutton(app.UIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.Text_inputEditFieldLabel = uilabel(app.UIFigure);
            app.Text_inputEditFieldLabel.Text = 'Text_input';
            app.Text_inputEditField = uieditfield(app.UIFigure, 'text');
            app.Text_inputEditField.Value = 'ФИО: Чинда Аделе     Гр.: 12002005';
            app.Text_dispButton = uibutton(app.UIFigure, 'push');
            app.Text_dispButton.ButtonPushedFcn = createCallbackFcn(app, @Text_dispButtonPushed, true);
            app.Text_dispButton.Text = 'Text_disp';
            app.InfoEditFieldLabel = uilabel(app.UIFigure);
            app.InfoEditFieldLabel.Text = 'Info';
            app.InfoEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assignment5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end