classdef sample_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        AngleEditField       matlab.ui.control.EditField
        AngleEditFieldLabel  matlab.ui.control.Label
        RotateButton         matlab.ui.control.Button
        OpenFileButton       matlab.ui.control.Button
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            [fname,pname] = uigetfile('*.*','Enter data file'); % выбор изображения
            global A; % создание глобальной переменной А
            A=imread(fullfile(pname, fname)); % считывание выбранного изображения
            imshow(A,'Parent',app.UIAxes); title(app.UIAxes, "Source Image");
            % вывод выбранного изображения на форму
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            global A; % создание глобальной переменной А
I=imrotate(A, app.AngleEditField.Value); % поворот изображение на указанное количество градусов
imshow(I,'Parent',app.UIAxes2); title(app.UIAxes2, "Rotation Result");
% вывод результирующего изображения на форму
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes2.Visible = 'off';
            app.OpenFileButton = uibutton(app.UIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'text');
            app.AngleEditField.Value = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sample_app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end