classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label           matlab.ui.control.Label
        IdentifyButton  matlab.ui.control.Button
        OpenFileButton  matlab.ui.control.Button
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
             [fname,pname] = uigetfile('*.*','Enter data file'); % выбор изображения
            global A; % создание глобальной переменной А
            A=imread(fullfile(pname, fname)); % считывание выбранного изображения
            imshow(A,'Parent',app.UIAxes); title(app.UIAxes, "Source Image");
            % вывод выбранного изображения на форму
        end

        % Button pushed function: IdentifyButton
        function IdentifyButtonPushed(app, event)
            global A;
            % imshow(A)
            Rmin = 30;
            Rmax = 65;

            [centersBright, radiiBright] = imfindcircles(A,[Rmin
                Rmax],'ObjectPolarity','bright');
            [centersDark, radiiDark] = imfindcircles(A,[Rmin
                Rmax],'ObjectPolarity','dark');
            viscircles(centersDark, radiiDark,'LineStyle','--');
            viscircles(centersBright, radiiBright,'Color','b');
            imshow(A,'Parent',app.UIAxes2); title(app.UIAxes2, "Identification Result");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            app.UIAxes2.Visible = 'off';
            app.OpenFileButton = uibutton(app.UIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.IdentifyButton = uibutton(app.UIFigure, 'push');
            app.IdentifyButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyButtonPushed, true);
            app.IdentifyButton.Text = 'Identify';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ФИО: Чинда Аделе     Гр.: 12002005';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end