classdef radius_estimator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Label           matlab.ui.control.Label
        IdentifyButton  matlab.ui.control.Button
        OpenFileButton  matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
                [fname,pname] = uigetfile('*.*','Enter data file'); % выбор изображения
            global A; % создание глобальной переменной А
            A=imread(fullfile(pname, fname)); % считывание выбранного изображения
            imshow(A,'Parent',app.UIAxes); title(app.UIAxes, "Source Image");
            % вывод выбранного изображения на форму
        end

        % Button pushed function: IdentifyButton
        function IdentifyButtonPushed(app, event)
         global A;
            imshow(A);
% hTxt = text(15,15,'Estimate radius of the roll of tape','FontWeight','bold','Color','y');
Rmin = 30;
Rmax = 500;
[center, radius] = imfindcircles(A,[Rmin Rmax],'Sensitivity',0.9);

% imshow(RGB);
% Display the circle
viscircles(center,radius);
% Display the calculated center
hold on;
plot(center(:,1),center(:,2),'yx','LineWidth',2);
hold off;
message = sprintf('The estimated radius is %2.1f pixels', radius);
text(15,15,message,'Color','y','FontWeight','bold');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.Visible = 'off';
            app.OpenFileButton = uibutton(app.UIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.IdentifyButton = uibutton(app.UIFigure, 'push');
            app.IdentifyButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyButtonPushed, true);
            app.IdentifyButton.Text = 'Identify';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ФИО: Чинда Аделе     Гр.: 12002005';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = radius_estimator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end